classdef gui_apq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SolucinaimplementarLabel  matlab.ui.control.Label
        CargadedatosexperimentalesLabel  matlab.ui.control.Label
        VelocidadButton           matlab.ui.control.Button
        PosicinButton             matlab.ui.control.Button
        DataexperimentalButton    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tiempo
        f_theta
        x
        F
        data_pq
        Rapidez
        V
        t
        vel_ang
        wave
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DataexperimentalButton
        function DataexperimentalButtonPushed(app, event)
        app.data_pq = readtable('pendulo_1_pq.csv');
        syms t theta_m w_n phi
        tetha = theta_m*sin(w_n*t + phi);
        v = diff(tetha, t);
        phi = acos(0);
        % Datos del ángulo pequeño
        Tiempo = app.data_pq(4:61,"Var1");
        app.tiempo = table2array(Tiempo);
        t_0 = min(app.tiempo);
        app.tiempo = app.tiempo - t_0;
        F_angulo1 = app.data_pq(4:61,"Var2");
        f_angulo1 = deg2rad(table2array(F_angulo1));
       
        
        % Ángulo inicial pequeño
        theta_0 = (max(f_angulo1) - min(f_angulo1))/2;
        
        % Corrimiento
        app.f_theta = theta_0 + f_angulo1;
        
        % Amplitud
        theta_m = theta_0/sin(phi);
        app.F = @(x,xdata)theta_m*(sin(x(1)*xdata + phi));
        x0 = [6]; % Valor inicial para la iteración
        [app.x,resnorm,~,exitflag,output] = lsqcurvefit(app.F,x0,app.tiempo,app.f_theta);
        app.V = (theta_m)*app.x(1)*cos(app.x(1)*app.tiempo + phi);

        rapidez = app.data_pq(4:61,"Var3");
        app.Rapidez = deg2rad(table2array(rapidez));
        Ode = sim('pq_onda.slx');
        app.t= Ode.pos_pq(:,1);
        app.wave= Ode.pos_pq(:,2);
        app.vel_ang = Ode.vel_pq(:,2);


        plot(app.UIAxes, app.tiempo,app.f_theta, 'bo')
        legend(app.UIAxes,'Real')
        xlabel(app.UIAxes,'Tiempo (s)');
        ylabel(app.UIAxes, 'Theta (θ)');
        end

        % Button pushed function: VelocidadButton
        function VelocidadButtonPushed(app, event)
      
        plot(app.UIAxes,app.tiempo,app.Rapidez,'bo',app.tiempo,app.V,'g',app.t,app.vel_ang,'--r')
        legend(app.UIAxes,'Real','Solución aproximada','Simulink-ODE45')
        xlabel(app.UIAxes,'Tiempo (s)');
        ylabel(app.UIAxes,'Velocidad angular (rad/s)');
        end

        % Button pushed function: PosicinButton
        function PosicinButtonPushed(app, event)
      
        plot(app.UIAxes,app.tiempo,app.f_theta,'bo',app.tiempo,app.F(app.x,app.tiempo),'g',app.t,app.wave,'--r')
        legend(app.UIAxes,'Real','Solución aproximada','Simulink-ODE45', 'Location', 'southwest')
        xlabel(app.UIAxes,'Tiempo (s)');
        ylabel(app.UIAxes,'Posición angular (rad)');
        end

        % Callback function
        function AproximacinEditFieldValueChanged(app, event)
            value = app.AproximacionEditField.Value;
            
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angulo pequeño')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataexperimentalButton = uibutton(app.UIFigure, 'push');
            app.DataexperimentalButton.ButtonPushedFcn = createCallbackFcn(app, @DataexperimentalButtonPushed, true);
            app.DataexperimentalButton.Text = 'Data experimental';
            app.PosicinButton = uibutton(app.UIFigure, 'push');
            app.PosicinButton.ButtonPushedFcn = createCallbackFcn(app, @PosicinButtonPushed, true);
            app.PosicinButton.Text = 'Posición';
            app.VelocidadButton = uibutton(app.UIFigure, 'push');
            app.VelocidadButton.ButtonPushedFcn = createCallbackFcn(app, @VelocidadButtonPushed, true);
            app.VelocidadButton.Text = 'Velocidad';
            app.CargadedatosexperimentalesLabel = uilabel(app.UIFigure);
            app.CargadedatosexperimentalesLabel.Text = 'Carga de datos experimentales';
            app.SolucinaimplementarLabel = uilabel(app.UIFigure);
            app.SolucinaimplementarLabel.Text = 'Solución a implementar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_apq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end